classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        CompExpTab                    matlab.ui.container.Tab
        CompresinSpinnerLabel         matlab.ui.control.Label
        CompresinSpinner              matlab.ui.control.Spinner
        ExpansinSpinnerLabel          matlab.ui.control.Label
        ExpansinSpinner               matlab.ui.control.Spinner
        CompExpButton                 matlab.ui.control.Button
        CuantificacinTab              matlab.ui.container.Tab
        NmerodebitsSpinnerLabel       matlab.ui.control.Label
        NmerodebitsSpinner            matlab.ui.control.Spinner
        AplicarCuantButton            matlab.ui.control.Button
        AmpAttTab                     matlab.ui.container.Tab
        AmplificarSpinnerLabel        matlab.ui.control.Label
        AmplificarSpinner             matlab.ui.control.Spinner
        AtenuarSpinnerLabel           matlab.ui.control.Label
        AtenuarSpinner                matlab.ui.control.Spinner
        AplicarAtenuButton            matlab.ui.control.Button
        InversinTab                   matlab.ui.container.Tab
        InvertirsealButton            matlab.ui.control.Button
        IntpDiezTab                   matlab.ui.container.Tab
        FactInterpolacionEditFieldLabel  matlab.ui.control.Label
        FactInterpolacionEditField    matlab.ui.control.NumericEditField
        FactdiezmadoEditFieldLabel    matlab.ui.control.Label
        FactdiezmadoEditField         matlab.ui.control.NumericEditField
        AplicarIntDiez                matlab.ui.control.Button
        DesfaseTab                    matlab.ui.container.Tab
        DesfasefrecuenciaDelayLabel   matlab.ui.control.Label
        DelayEditFieldLabel           matlab.ui.control.Label
        DelayEditField                matlab.ui.control.NumericEditField
        AplicarButton                 matlab.ui.control.Button
        msLabel                       matlab.ui.control.Label
        EfectosTab                    matlab.ui.container.Tab
        AadirefectosButton            matlab.ui.control.Button
        GananciaEcoFlangerEditFieldLabel  matlab.ui.control.Label
        GananciaEcoFlangerEditField   matlab.ui.control.NumericEditField
        DelayEcoFlangerLabel          matlab.ui.control.Label
        DelayEcoFlangerEditField      matlab.ui.control.NumericEditField
        EcoCheckBox                   matlab.ui.control.CheckBox
        FlangerCheckBox               matlab.ui.control.CheckBox
        FuzzCheckBox                  matlab.ui.control.CheckBox
        MixFuzzEditFieldLabel         matlab.ui.control.Label
        MixFuzzEditField              matlab.ui.control.NumericEditField
        VLabel_2                      matlab.ui.control.Label
        msLabel_2                     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        GenSenalesPanel               matlab.ui.container.Panel
        TipodeondaDropDownLabel       matlab.ui.control.Label
        TipoOnda                      matlab.ui.control.DropDown
        AmplitudLabel                 matlab.ui.control.Label
        AmplitEditField               matlab.ui.control.EditField
        VLabel                        matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        FrecEditField                 matlab.ui.control.EditField
        FrecuenciaLabel               matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.EditField
        DuracinLabel                  matlab.ui.control.Label
        GenerarButton                 matlab.ui.control.Button
        HzLabel_2                     matlab.ui.control.Label
        FrecmuestreoEditField         matlab.ui.control.EditField
        FrecmuestreoLabel             matlab.ui.control.Label
        ControldebuferPanel           matlab.ui.container.Panel
        Buffer                        matlab.ui.control.ListBox
        Seal1Panel                    matlab.ui.container.Panel
        MostrargrficasButton          matlab.ui.control.Button
        Seal2Panel                    matlab.ui.container.Panel
        MostrargrficasButton_2        matlab.ui.control.Button
        CombinarsealesButton          matlab.ui.control.Button
        EliminarsealButton            matlab.ui.control.Button
        CancelarButton                matlab.ui.control.Button
        CombinarButton                matlab.ui.control.Button
        Filtros                       matlab.ui.container.Panel
        FrecdecorteLabel              matlab.ui.control.Label
        FrecCorteEditField            matlab.ui.control.EditField
        HzLabel_6                     matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        FrecCorte2EditField           matlab.ui.control.EditField
        afrecdecorteLabel             matlab.ui.control.Label
        AplicarFiltroButton           matlab.ui.control.Button
        FiltroDropDownLabel           matlab.ui.control.Label
        TipoFiltro                    matlab.ui.control.DropDown
        OrdenLabel                    matlab.ui.control.Label
        MitadOrdenBPEditField         matlab.ui.control.EditField
        GananciaEditFieldLabel        matlab.ui.control.Label
        GananciaEditField             matlab.ui.control.EditField
        ControldegrficasPanel         matlab.ui.container.Panel
        Grfica3ButtonGroup            matlab.ui.container.ButtonGroup
        Canal1Espectrograma           matlab.ui.control.Button
        Canal2Espectrograma           matlab.ui.control.Button
        Grfica4ButtonGroup            matlab.ui.container.ButtonGroup
        Canal1Espectrograma_2         matlab.ui.control.Button
        Canal2Espectrograma_2         matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        Grfica1ButtonGroup            matlab.ui.container.ButtonGroup
        Canal1Tiempo                  matlab.ui.control.Button
        Canal2Tiempo                  matlab.ui.control.Button
        Grfica2ButtonGroup            matlab.ui.container.ButtonGroup
        Canal1Tiempo_2                matlab.ui.control.Button
        Canal2Tiempo_2                matlab.ui.control.Button
        Grfica5ButtonGroup            matlab.ui.container.ButtonGroup
        Canal1Frecuencia1             matlab.ui.control.Button
        Canal2Frecuencia2             matlab.ui.control.Button
        Grfica6ButtonGroup            matlab.ui.container.ButtonGroup
        Canal1Frecuencia1_2           matlab.ui.control.Button
        Canal2Frecuencia2_2           matlab.ui.control.Button
        Grficastiempo1y2Label         matlab.ui.control.Label
        Grficasespectrograma3y4Label  matlab.ui.control.Label
        Grficasfrecuencia5y6Label     matlab.ui.control.Label
        InformacindelasealPanel       matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        ModulacinPanel                matlab.ui.container.Panel
        TipoDropDownLabel             matlab.ui.control.Label
        TipoDropDown                  matlab.ui.control.DropDown
        HzLabel_7                     matlab.ui.control.Label
        FrecPortField                 matlab.ui.control.EditField
        FrecportadoraLabel            matlab.ui.control.Label
        ModulacionButton              matlab.ui.control.Button
        DemodularButton               matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        EspectogramaTab               matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        VisualizarcolorbarCheckBox    matlab.ui.control.CheckBox
        SealenfrecuenciaTab_2         matlab.ui.container.Tab
        UIAxes5                       matlab.ui.control.UIAxes
        TabGroup3                     matlab.ui.container.TabGroup
        EspectrogramaTab              matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        Visualizarcolorbar2CheckBox   matlab.ui.control.CheckBox
        SealenfrecuenciaTab           matlab.ui.container.Tab
        UIAxes6                       matlab.ui.control.UIAxes
        GeneradorderuidoPanel         matlab.ui.container.Panel
        TipoderuidoDropDownLabel      matlab.ui.control.Label
        TipoderuidoDropDown           matlab.ui.control.DropDown
        CanalesDropDown_2Label        matlab.ui.control.Label
        CanalesRuido                  matlab.ui.control.DropDown
        HzLabel_9                     matlab.ui.control.Label
        FrecmuestreoRuido             matlab.ui.control.EditField
        FsLabel                       matlab.ui.control.Label
        AplicarRuidoButton            matlab.ui.control.Button
        nmuestrasSpinnerLabel         matlab.ui.control.Label
        nmuestrasSpinner              matlab.ui.control.Spinner
        Panel                         matlab.ui.container.Panel
        CargaButton                   matlab.ui.control.Button
        GuardarButton                 matlab.ui.control.Button
        HzLabel_8                     matlab.ui.control.Label
        FsGrabacionField              matlab.ui.control.EditField
        FsrecLabel                    matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        RecordButton                  matlab.ui.control.StateButton
        nbitsDropDownLabel            matlab.ui.control.Label
        nbitsDropDown                 matlab.ui.control.DropDown
        CanalesDropDownLabel          matlab.ui.control.Label
        CanalesDropDown               matlab.ui.control.DropDown
        Panel_2                       matlab.ui.container.Panel
        DibujarCargaCheckBox          matlab.ui.control.CheckBox
        DibujarEfectoCheckBox         matlab.ui.control.CheckBox
        InfoCheckBox                  matlab.ui.control.CheckBox
        VisualizarReproduccion        matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        pointer=0; %Puntero apunta siempre a posicion de ultima señal almacenada
        
        data; %Datos crudos de audio de lectura del fichero
        
        %Variables para control de reproduccion
        recorder; %Objeto de grabacion por micro
        nrecord=0;
        
        %Variables para control de reproduccion
        old_p=0; %Puesta a 0 para que no coincida nunca la primera vez que se da a play
        audio; %Objeto de audio para reproduccion
        firstplay=1; %variable de control que uso para evitar borrar las señales ya ploteada, mirar mostrarInstante
        
        desfase=0; %Variable auxiliar para mostrar mensaje en el tab de desfase
    end
    
    methods (Access = private)
        
        %%%%%FUNCIONES PARA LA MUESTRA DE INFORMACION POR PANTALLA%%%%%
        
        function mostrarInfo(app,posicion)
            fs=app.data(posicion).fs;
            nmuestras=length(app.data(posicion).signal);
            duracion=nmuestras/fs;
            ncanales=size(app.data(posicion).signal,2);
            potencia=sum(abs(app.data(posicion).signal(:)).^2)/nmuestras;
            
            app.TextArea.Value=sprintf( ...
                'Duración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nPotencia: %.2f W\n\nMuestras totales: %.0f\n\nNúmero de canales: %.0f', ...
                string(duracion), ...
                string(fs), ...
                string(potencia),...
                string(nmuestras), ...
                string(ncanales));
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%FUNCIONES PARA EL MANEJO DE LOS PLOTS%%%%%%%%%%%
       
        %funciones para mostrar info en plots (sepodria reducir a dos pero
        %voy a picatecla)
        function dibPlotTiempos1(app,base,datos,fs)
            reset(app.UIAxes); %Limpamos los datos de la grafica antes de dibujar
            plot(app.UIAxes,base,datos);
            xlabel(app.UIAxes,'Tiempo [s]');
            ylabel(app.UIAxes,'Amplitud [V]');
            app.UIAxes.XLim = [0 (1/fs)*length(datos)];
            app.UIAxes.YLim=[min(datos(:)) max(datos(:))];
        end  
        
        function dibPlotTiempos2(app,base,datos,fs)
            reset(app.UIAxes2); %Limpamos grafica antes de dibujar
            plot(app.UIAxes2,base,datos);
            xlabel(app.UIAxes2,'Tiempo [s]');
            ylabel(app.UIAxes2,'Amplitud [V]');
            app.UIAxes2.XLim = [0 (1/fs)*length(datos)];
            app.UIAxes2.YLim=[min(datos(:)) max(datos(:))];
        end  
        
        function dibPlotFrecuencias1(app,f,Y)
            reset(app.UIAxes5); %Limpamos grafica antes de dibujar
            colorbar(app.UIAxes5,'off');
            plot(app.UIAxes5,f,2*abs(Y));
            xlabel(app.UIAxes5,'Frecuencia [Hz]');
            ylabel(app.UIAxes5,'Amplitud [V]');
        end  
        
        function dibPlotFrecuencias2(app,f,Y)
            reset(app.UIAxes6); %Limpamos grafica antes de dibujar
            colorbar(app.UIAxes6,'off');
            plot(app.UIAxes6,f,2*abs(Y));
            xlabel(app.UIAxes6,'Frecuencia [Hz]');
            ylabel(app.UIAxes6,'Amplitud [V]');
        end 
        
        function dibPlotEspect1(app,t,f,s,posicion)
            reset(app.UIAxes3); %Limpamos grafica antes de dibujar
            colorbar(app.UIAxes3,'off');
            
            imagesc(app.UIAxes3,t,f,10*log10(abs(s)));   %Logaritmo del espectro
            xlabel(app.UIAxes3,'Tiempo [s]');
            ylabel(app.UIAxes3,'Frecuencia [Hz]');
            app.UIAxes3.YDir='normal';   %Pone las frecuencias bajas abajo
            app.UIAxes3.XLim = [0 (1/app.data(posicion).fs)*length(app.data(posicion).signal)];
            app.UIAxes3.YLim=[min(f) max(f)];
            
            c=colorbar(app.UIAxes3);
            c.Label.String='Potencia [dBm]';
            
            if app.VisualizarcolorbarCheckBox.Value==0
                colorbar(app.UIAxes3,'off');
            end
        end 
        
        function dibPlotEspect2(app,t,f,s,posicion)
            reset(app.UIAxes4); %Limpamos grafica antes de dibujar
            colorbar(app.UIAxes4,'off');
            
            imagesc(app.UIAxes4,t,f,10*log10(abs(s)));   %Logaritmo del espectro
            xlabel(app.UIAxes4,'Tiempo [s]');
            ylabel(app.UIAxes4,'Frecuencia [Hz]');
            app.UIAxes4.YDir='normal';
            app.UIAxes4.XLim = [0 (1/app.data(posicion).fs)*length(app.data(posicion).signal)];
            app.UIAxes4.YLim=[min(f) max(f)];
            
            c=colorbar(app.UIAxes4);
            c.Label.String='Potencia [dBm]';
            
            if app.Visualizarcolorbar2CheckBox.Value==0
                colorbar(app.UIAxes4,'off');
            end
        end 
        
        function draw1(app,posicion)
            [t,f]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));
            
            TF=(1/length(app.data(posicion).signal)) * fft(app.data(posicion).signal);
            TF=TF(1:fix(length(TF)/2)+1,:);
            [S,F,T] = spectrogram(app.data(posicion).signal(:,1)',1000,[],[],app.data(posicion).fs);       %Hacemos el espectrograma de un solo canal
            
            dibPlotEspect1(app,T,F,S,posicion);
            dibPlotTiempos1(app,t,app.data(posicion).signal,app.data(posicion).fs);
            dibPlotFrecuencias1(app,f,TF);
            
            title(app.UIAxes,app.data(posicion).nombre+' en tiempo');
            title(app.UIAxes3,'Espectrograma de '+app.data(posicion).nombre);
            title(app.UIAxes5,app.data(posicion).nombre+' en frecuencia');
        end
        
        function draw2(app,posicion)
            [t,f]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));
            
            TF=(1/length(app.data(posicion).signal)) * fft(app.data(posicion).signal);
            TF=TF(1:fix(length(TF)/2)+1,:);
            [S,F,T] = spectrogram(app.data(posicion).signal(:,1)',1000,[],[],app.data(posicion).fs);       %Hacemos el espectrograma de un solo canal
            
            dibPlotEspect2(app,T,F,S,posicion);
            dibPlotTiempos2(app,t,app.data(posicion).signal,app.data(posicion).fs);
            dibPlotFrecuencias2(app,f,TF);
            
            title(app.UIAxes2,app.data(posicion).nombre+' en tiempo');
            title(app.UIAxes4,'Espectrograma de '+app.data(posicion).nombre);
            title(app.UIAxes6,app.data(posicion).nombre+' en frecuencia');
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%FUNCIONES PARA LA CREACION DE BASES%%%%%%%%%%%%%%%%%%%
        function [t,f]=createBase(~,fs,nmuestras)
            Ts=1/fs;
            t=0:Ts:(nmuestras*Ts-Ts);
            f=linspace(0,fs/2,fix(nmuestras/2)+1);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%FUNCIONES CLAVE PARA LA LOGICA DEL BUFFER (POSICION E INCLUSION DE DATOS)%%%%%%%%%%%%%%%
        
        function addItem(app,cadena)                                        %añade elemento a la lista, no a vector de estructuras. Eliminar elemento implementado en EliminarsealButton
            app.Buffer.Items=[app.Buffer.Items cadena];                     %Añade la cadena a los items
            app.Buffer.Value=app.Buffer.Items(length(app.Buffer.Items));    %Selecciona la ultima cadena
        end
        
        function posicion=getSelposicion(app) %asignamos el valor del apuntador a su posicion en la lista
            posicion=1;
            items=app.Buffer.Items;
            
            if length(items)~=0
                for i=1:length(items)
                    if strcmp(app.Buffer.Value,items(i))
                        return;
                    else
                        posicion=posicion+1;
                    end
                end
                
            else
                posicion=0;%devolvera 0 si no encuentra coincidencia
            end
        end
        
        function [posicion1,posicion2]=getMultiposicion(app)                %asignamos el valor del apuntador a su posicion en la lista
            items=app.Buffer.Items;
            
            if length(items)~=0
                posicion1=1;
                
                for i=1:length(items)
                    if strcmp(app.Buffer.Value(1),items(i))
                        break;
                    else
                        posicion1=posicion1+1;
                    end
                end
                
            else
                posicion1=0;%devolvera 0 si no encuentra coincidencia
            end
            
            if length(items)~=0
                posicion2=1;
                
                for i=1:length(items)
                    if strcmp(app.Buffer.Value(2),items(i))
                        return;
                    else
                        posicion2=posicion2+1;
                    end
                end
                
            else
                posicion2=0;%devolvera 0 si no encuentra coincidencia
            end
        end
        
        function includeData(app,signal,fs,nombre) %añade elemento a vector de estructuras
            app.pointer=app.pointer+1;
            
            if(app.pointer==1) %distinta sintaxis dependiendo de cuantos elementos hay en la tabla
                app.data=struct('signal',signal,'fs',fs,'nombre',nombre);
            else
                app.data=[app.data struct('signal',signal,'fs',fs,'nombre',nombre)];
            end
        end
        
        function eliminaDato(app,posicion)
            app.data(posicion)=[];
            app.pointer=app.pointer-1;
        end
        
        %%%%%%%%%%%%%%%%FUNCIONES PARA CHECKBOXES%%%%%%%%%%%%%%%%%%%%%
        function checkInfo(app,posicion)
            if app.InfoCheckBox.Value == 1
                mostrarInfo(app,posicion);
            end
        end
        
        function checkPlotCarga(app,posicion)
            if app.DibujarEfectoCheckBox.Value == 1
                draw1(app,posicion);
                
                cla(app.UIAxes2,'reset');
                xlabel(app.UIAxes2,'Tiempo [s]');
                ylabel(app.UIAxes2,'Amplitud [V]');
                title(app.UIAxes2,'Gráfica 2');
                
                cla(app.UIAxes4,'reset');
                xlabel(app.UIAxes4,'Tiempo [s]');
                ylabel(app.UIAxes4,'Frecuencia [Hz]');
                title(app.UIAxes4,'Gráfica 4');
                
                cla(app.UIAxes6,'reset');
                xlabel(app.UIAxes6,'Frecuencia [Hz]');
                ylabel(app.UIAxes6,'Amplitud [V]');
                title(app.UIAxes6,'Gráfica 6');
            end
        end
        
        function checkPlotEfecto(app,referencia,posicion)
            if app.DibujarEfectoCheckBox.Value == 1
                draw1(app,posicion);
                draw2(app,referencia);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%%%%%%FUNCIONES DE EFECTOS%%%%%%%%%%%%%%%%%%%
        
        function y=generaDelay(~,xin,delay,fs)
            D=round(delay*fs);
            
            for i=D+1:length(xin)
                y(i)=xin(i-D);      %Sumas la ganancia con la señal retardada por el delay
            end
        end
        
        function y=generaEco(~,xin,delay,ganancia,fs)
            D=round(delay*fs);
            y=zeros(size(xin));
            
            y(1:D)=xin(1:D);     %Tomo las primeras muestras iguales
            
            for i=D+1:length(xin)
                y(i)=xin(i)+ganancia*xin(i-D);      %Sumas la ganancia con la señal retardada por el delay
            end
        end
        
        function y=generaFlanger(~,xin,delay,ganancia,fs)
            index=1:length(xin);
            seno_ref=(sin(2*pi*index*(1/fs)))';     %seno referencia para el delay
            delay_samp=round(delay*fs);             %max delay en las muestras
            
            y=zeros(length(xin),1);
            y(1:delay_samp)=xin(1:delay_samp);
                
            for i=(delay_samp+1):length(xin)
                seno=abs(seno_ref(i));
                delay_seno=ceil(seno*delay_samp);
                y(i)=xin(i) + ganancia*(xin(i-delay_seno));
            end
        end
        
        function y=generaFuzz(~,xin,ganancia,mix)
            q=xin*ganancia/max(abs(xin));
            z=sign(-q).*(1-exp(sign(-q).*q));
            
            y=mix*z*max(abs(xin))/max(abs(z))+(1-mix)*xin;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %%%%%%%FUNCION PARA INFORMACION VISUAL EN REPRODUCCION%%%%%%%%
        function mostrarInstante(app)
            if app.firstplay==0                                     %este if es para evitar que me borre la señal dibujada
                
                children=get(app.UIAxes,'children');
                delete(children(1));                                %con estas lineas borro lo ultimo dibujado en la grafica
                
                children=get(app.UIAxes3,'children');
                delete(children(1));                               %con estas lineas borro lo ultimo dibujado en la grafica
                
            else
                app.firstplay=0;
            end
            
            time=app.audio.CurrentSample*(1/app.audio.SampleRate); %hallo tiempo donde dibujar la linea
            xline(app.UIAxes,time,'-g');                            %dibujo lineas
            xline(app.UIAxes3,time,'-r');
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
        %%%%%%%%%%%%%%%%%%%%%%%%MISCELANEO%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function escondermostrar(app)
            if(strcmp(app.CombinarsealesButton.Visible,'on')==1)        
                app.CombinarsealesButton.Visible='off';
                app.CombinarsealesButton.Enable='off';
                app.EliminarsealButton.Visible='off';
                app.EliminarsealButton.Enable='off';
                
                app.CombinarButton.Visible='on';
                app.CombinarButton.Enable='on';
                app.CancelarButton.Visible='on';
                app.CancelarButton.Enable='on';
                
            elseif(strcmp(app.CombinarsealesButton.Visible,'off')==1)
                app.CombinarsealesButton.Visible='on';
                app.CombinarsealesButton.Enable='on';
                app.EliminarsealButton.Visible='on';
                app.EliminarsealButton.Enable='on';
                
                app.CombinarButton.Visible='off';
                app.CombinarButton.Enable='off';
                app.CancelarButton.Visible='off';
                app.CancelarButton.Enable='off';
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.Scrollable='on';
            drawnow; %parece mas robusto si se hace drawnow al final (al menos en portatiles)
            
            %las siguientes dos lineas añaden robustez
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: CargaButton
        function CargaButtonPushed(app, event)
            [file,path]=uigetfile({'*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a'...
                ,'Extensiones Audio (*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a)'},'Seleccione el archivo de audio'); 
            
            if isequal(file,0)
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                return
            end
            
            [signal, fs_lectura]=audioread(fullfile(path,file));      %fullfile concatena el path y el archivo para poder leerlo desde donde sea
            
            %por alguna razon solo se muestra directamente si hacemos esto
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            info=audioinfo(fullfile(path,file));
            aux=split(string(info.Filename),'\');%variable auxiliar para facilitar legibilidad de codigo
            
            includeData(app,signal,fs_lectura,string(aux(end)));
            addItem(app,string(aux(end)));
            
            %logica para la muestra de informacion
            checkInfo(app,app.pointer);%función para mostrar informacion
            checkPlotCarga(app,app.pointer);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion=getSelposicion(app);
            
            if posicion~=app.old_p && posicion>=1
                app.audio=audioplayer(app.data(posicion).signal,app.data(posicion).fs); %genero audio
                app.old_p=posicion; %guardo posicion de la señal que voy a reproducir
                app.firstplay=1; %guardo que he cargado una nueva señal
            end
            
            if (size(app.audio)~=0) 
                if app.VisualizarReproduccion.Value==1 %indico si la interrupcion TimerFcn llama a mi funcion o no (dibujo o no en grafica)
                    app.audio.TimerFcn=@(obj,event)mostrarInstante(app); %asigno la funcion al evento TimerFcn en el objeto de audio. Este evento salta cada 0.05s.
                else
                    app.audio.TimerFcn=[];
                end
                
                if(app.audio.SampleRate<200000) %play() no puede manejar tasa de muestreo mayores a 200000Hz
                    if strcmp(app.audio.Running,'off') %if anidado porque no puedo usar strmp hasta que me aseguro que se ha generado audio
                        if app.audio.CurrentSample==1 %estamos en el principio del audio
                            play(app.audio);
                        else
                            resume(app.audio);
                        end
                    end
                else
                    errordlg('La tasa de muestreo debe ser menor de 200KHz');
                end
            end
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;
            
            if value==1
                fs=str2double(app.FsGrabacionField.Value);
                aux=split(app.nbitsDropDown.Value,' ');
                nbits=str2double(string(aux(1)));
                ncanales=str2double(app.CanalesDropDown.Value);
                
                app.recorder=audiorecorder(fs,nbits,ncanales);
                record(app.recorder);
                
            elseif value==0
                app.nrecord=app.nrecord+1;
                
                stop(app.recorder);
                grabacion=getaudiodata(app.recorder);
                
                aux="grabacion Nº"+int2str(app.nrecord);
                
                includeData(app,grabacion,app.recorder.SampleRate,aux);
                addItem(app,aux);
                
                %logica extra para muestra de informacion
                checkInfo(app,app.pointer);
                checkPlotCarga(app,app.pointer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
          if size(app.audio)~=0
              if app.firstplay==0 %si esta a cero es que ha entrado a mostrarInstante
                  
                children=get(app.UIAxes,'children');
                delete(children(1)); %con estas lineas borro lo ultimo dibujado en la grafica
                
                children=get(app.UIAxes3,'children');
                delete(children(1)); %con estas lineas borro lo ultimo dibujado en la grafica
                
                app.firstplay=1; %como he parado señal voy a reproducir desde el principio
              end
              stop(app.audio);
          end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if size(app.audio)~=0
                if strcmp(app.audio.Running,'on')
                      pause(app.audio);
                end
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal del bufer de control por favor');
                return                
            end
            
            posicion=getSelposicion(app);
            
            if size(app.data(posicion).signal)~=0
                [file,path] = uiputfile({'*.wav';'*.mp4';'*.ogg';'*.flac';'*.m4a'},'Guarda la grabación','grabacion.wav'); 
                
                if isequal(file,0)
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                    return
                end
                
                audiowrite(fullfile(path,file),app.data(posicion).signal,app.data(posicion).fs);
            end


            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            % Generador de funciones elementales      
            % Parámetros introducidos por pantalla
            opcion=app.TipoOnda.Value;
            amplitud=str2double(app.AmplitEditField.Value);
            frecuencia=str2double(app.FrecEditField.Value);
            duracion=str2double(app.DurationEditField.Value);
            fs=str2double(app.FrecmuestreoEditField.Value);
            numMuestras=fs*duracion;
            
            if(fs<=2*frecuencia)
                msgbox('Tono generado con Aliasing, téngalo en cuenta');
            end
            
            [t,~]=createBase(app,fs,numMuestras);
            
            switch opcion
                case 'Seno'
                    y=amplitud * sin(2*pi*frecuencia * t);
                case 'Coseno'
                    y=amplitud * cos(2*pi*frecuencia * t);
                case 'Cuadrada'
                    y=amplitud * square(frecuencia * t);
                case 'Triangular'
                    y = amplitud * sawtooth(frecuencia * t, 0.5);
                case 'Sierra'
                    y = amplitud * sawtooth(frecuencia * t);    
                case 'Elegir..'%evitamos el error cuando das a generar sin seleccionar nada
                    errordlg('Elija una señal, por favor');
                    return;
            end
            
            aux="Señal "+opcion+ " " + app.FrecEditField.Value + "Hz";
            
            includeData(app,y',fs,aux);%y traspuesto por comodidad en el almacenamiento
            addItem(app,aux);
            
            %logica extra para muestra de informacion
            checkInfo(app,app.pointer);
            checkPlotCarga(app,app.pointer);
        end

        % Value changed function: Buffer
        function BufferValueChanged(app, event)
            if(strcmp(app.Buffer.Multiselect,'off') || (strcmp(app.Buffer.Multiselect,'on') && length(app.Buffer.Value)==1))  %Si no estamos combinando
                posicion=getSelposicion(app);
                mostrarInfo(app,posicion);              %función para mostrar informacion
                
            elseif(length(app.Buffer.Value)==2)          %Si estamos combinando
                %Como esta logica solo la usamos aqui no creamos funcion para ella
                
                [posicion1,posicion2]=getMultiposicion(app);
                
                %Datos de la primera señal
                fs1=app.data(posicion1).fs;
                nmuestras1=length(app.data(posicion1).signal);
                duracion1=nmuestras1/fs1;
                ncanales1=size(app.data(posicion1).signal,2);
                potencia1=sum(abs(app.data(posicion1).signal(:,1)).^2)/nmuestras1;
                
                %Datos de la segunda señal
                fs2=app.data(posicion2).fs;
                nmuestras2=length(app.data(posicion2).signal);
                duracion2=nmuestras2/fs2;
                ncanales2=size(app.data(posicion2).signal,2);
                potencia2=sum(abs(app.data(posicion2).signal(:,1)).^2)/nmuestras2;
                
                %Se muestran los datos por pantalla
                app.TextArea.Value=sprintf( ...
                ['Datos de la primera señal:\n\n' ...
                'Duración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nPotencia: %.5f W\n\nMuestras totales: %.0f\n\nNúmero de canales: %.0f\n\n'...
                '----------------------------------------'...
                '\nDatos de la segunda señal:\n\n'...
                'Duración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nPotencia: %.5f W\n\nMuestras totales: %.0f\n\nNúmero de canales: %.0f\n\n'],... 
                string(duracion1), ...
                string(fs1), ...
                string(potencia1),...
                string(nmuestras1), ...
                string(ncanales1),...
                string(duracion2), ...
                string(fs2), ...
                string(potencia2),...
                string(nmuestras2), ...
                string(ncanales2));
                
            elseif(length(app.Buffer.Value)>2 && strcmp(app.Buffer.Multiselect,'on'))
                errordlg('Seleccione solo 2 señales por favor');
                
                app.Buffer.Value=app.Buffer.Items(length(app.Buffer.Items));
                posicion=length(app.Buffer.Items);
                
                mostrarInfo(app,posicion);    %función para mostrar informacion
            end
            
            
        end

        % Button pushed function: MostrargrficasButton_2
        function MostrargrficasButton_2Pushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion=getSelposicion(app);
            
            if (posicion > 0 && strcmp(app.Buffer.Multiselect,'off'))
                draw2(app,posicion);
            end
        end

        % Button pushed function: CompExpButton
        function CompExpButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end    
            
            posicion=getSelposicion(app);
            
            signal=app.data(posicion).signal;
            aux=app.data(posicion).nombre;
            
            if (app.ExpansinSpinner.Value>1 || app.CompresinSpinner.Value>1)
                if(app.CompresinSpinner.Value>1)
                    %Calculo la nueva frecuencia de muestreo
                    fs_new=app.data(posicion).fs*app.CompresinSpinner.Value;
                    aux=aux+", comp x"+int2str(app.CompresinSpinner.Value);
                end
                
                if(app.ExpansinSpinner.Value>1)
                    %Calculo la nueva frecuencia de muestreo
                    if app.CompresinSpinner.Value~=1
                        fs_new=fs_new/app.ExpansinSpinner.Value;
                    else
                        fs_new=app.data(posicion).fs/app.ExpansinSpinner.Value;
                    end
                    aux=aux+", exp x"+int2str(app.ExpansinSpinner.Value);
                end
                
                includeData(app,signal,fs_new,aux);
                addItem(app,aux);
                
                checkInfo(app,app.pointer);
                checkPlotEfecto(app,posicion,app.pointer);
                
            elseif (app.ExpansinSpinner.Value<1 || app.CompresinSpinner.Value<1)
                errordlg('No introduzca términos menores de 1 por favor')
            end
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion=getSelposicion(app);
            
            signal=app.data(posicion).signal;
            fs=app.data(posicion).fs;
            
            % Parámetros introducidos por pantalla
            opcion=app.TipoFiltro.Value;
            fc=str2double(app.FrecCorteEditField.Value);
            fc2=str2double(app.FrecCorte2EditField.Value);
            orden=str2double(app.MitadOrdenBPEditField.Value);
            ganancia=str2double(app.GananciaEditField.Value);
            
            switch opcion
                case 'Paso alto'
                    %b y a son los coeficientes de función de transferencia de un filtro Butterworth 
                    [b,a]=butter(orden,fc/(fs/2),'high');
                    filtrado=filter(b,a,signal);
                    
                case 'Paso bajo'
                    [b,a]=butter(orden,fc/(fs/2),'low');
                    filtrado=filter(b,a,signal);
                    
                case 'Paso banda'
                    [b,a]=butter(ceil(orden/2),[fc/(fs/2),fc2/(fs/2)],'bandpass');
                    filtrado=filter(b,a,signal);
                    if(fc2<fc)
                        errordlg('La segunda frecuencia de corte no puede ser menor que la primera','Error');
                    end
                    
                case 'Elimina banda'
                    [b,a]=butter(ceil(orden/2),[fc/(fs/2),fc2/(fs/2)],'stop');
                    filtrado=filter(b,a,signal); 
                    if(fc2<fc)
                        errordlg('La segunda frecuencia de corte no puede ser menor que la primera','Error');
                        return
                    end
                    
                case 'Elegir..'
                    errordlg('Elija una señal, por favor');
                    return;
            end
            
            aux=app.data(posicion).nombre+", filt "+opcion;
            
            includeData(app,filtrado*ganancia,fs,aux);
            addItem(app,aux);
            
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);
        end

        % Button pushed function: EliminarsealButton
        function EliminarsealButtonPushed(app, event)
        if(length(app.Buffer.Items)~=0)    
            posicion=getSelposicion(app);
            
            app.Buffer.Items(posicion)=[];
            eliminaDato(app,posicion);
            app.old_p=0;%para evitar bug al borrar de la lista e intentar reproducir.
            
        else
            errordlg('No hay señales que eliminar');
        end
        end

        % Button pushed function: CombinarsealesButton
        function CombinarsealesButtonPushed(app, event)
            if(length(app.Buffer.Items)>=2)
                escondermostrar(app);
                msgbox('Selecciona dos señales con Ctrl + click');
                app.Buffer.Multiselect='on';
                
            else
                errordlg('No hay suficientes señales para combinar');
            end
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            escondermostrar(app);
            
            app.Buffer.Multiselect='off';
            app.Buffer.Value=app.Buffer.Items(length(app.Buffer.Items));    %Muestro el ultimo valor para evitar fallo de dejar 2 señales mostradas
            
            posicion=length(app.Buffer.Items);
                
            mostrarInfo(app,posicion);    %función para mostrar informacion
            
        end

        % Button pushed function: CombinarButton
        function CombinarButtonPushed(app, event)
            if(length(app.Buffer.Value)==2)
                [posicion1,posicion2]=getMultiposicion(app);
                
                %Datos de las señales
                
                fs1=app.data(posicion1).fs;
                fs2=app.data(posicion2).fs;
                fs=max(fs1,fs2);
                
                y1 = app.data(posicion1).signal;
                y2 = app.data(posicion2).signal;
                
                canales1=size(y1,2);
                canales2=size(y2,2);
                
                nfilas1=size(y1,1);
                nfilas2=size(y2,1);
                
                if canales1>canales2 %señal 1 con mas canales que la 2
                    if nfilas1>=nfilas2 %señal 1 igual o mas larga que la 2
                        y2=[y2; zeros(nfilas1-nfilas2,1)];
                    elseif nfilas1<nfilas2 %señal 2 mas larga que la 1
                        y1=[y1; zeros(nfilas2-nfilas1,2)];
                    end
                    
                    y(:,1)=y1(:,1)+y2;
                    y(:,2)=y1(:,2)+y2;
                    
                elseif canales1<canales2 %señal 2 con mas canales que la 1
                    if nfilas1>=nfilas2
                        y2=[y2; zeros(nfilas1-nfilas2,2)];
                    elseif nfilas1<nfilas2
                        y1=[y1; zeros(nfilas2-nfilas1,1)];
                    end
                    
                    y(:,1)=y2(:,1)+y1;
                    y(:,2)=y2(:,2)+y1;
                    
                else %señal 1 y 2 con mismo numero de canales
                    if nfilas1>=nfilas2
                        y2=[y2; zeros(nfilas1-nfilas2,canales1)];
                    elseif nfilas1<nfilas2
                        y1=[y1; zeros(nfilas2-nfilas1,canales1)];
                    end
                    
                    y=y1+y2;
                end
                
                aux=app.data(posicion1).nombre + " y " + app.data(posicion2).nombre + " combinadas";
                
                includeData(app,y,fs,aux);
                addItem(app,aux);
            
                checkInfo(app,app.pointer);
                checkPlotCarga(app,app.pointer);
            else
                errordlg('Escoja 2 señales por favor');
                return
            end
        end

        % Button pushed function: Canal1Espectrograma
        function Canal1EspectrogramaPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [S,F,T] = spectrogram(app.data(posicion).signal(:,1)',1000,[],[],app.data(posicion).fs);       %Hacemos el espectrograma de un solo canal
                dibPlotEspect1(app,T,F,S,posicion);
                title(app.UIAxes3,'Espectrograma del canal 1 de '+app.data(posicion).nombre);
            end
        end

        % Button pushed function: Canal2Espectrograma
        function Canal2EspectrogramaPushed(app, event)
            posicion=getSelposicion(app);
            
            if (posicion~=0 && size(app.data(posicion).signal,2)>1)
                [S,F,T] = spectrogram(app.data(posicion).signal(:,2)',1000,[],[],app.data(posicion).fs);       %Hacemos el espectrograma de un solo canal
                dibPlotEspect1(app,T,F,S,posicion);
                title(app.UIAxes4,'Espectrograma deñ canal 2 de '+app.data(posicion).nombre);
            else
                errordlg('La señal solo tiene 1 canal');
            end
        end

        % Button pushed function: Canal1Espectrograma_2
        function Canal1Espectrograma_2Pushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [S,F,T] = spectrogram(app.data(posicion).signal(:,1)',1000,[],[],app.data(posicion).fs);       %Hacemos el espectrograma de un solo canal
                dibPlotEspect2(app,T,F,S,posicion);
                title(app.UIAxes4,'Espectrograma del canal 1 de '+app.data(posicion).nombre);
            end
        end

        % Button pushed function: Canal2Espectrograma_2
        function Canal2Espectrograma_2Pushed(app, event)
            posicion=getSelposicion(app);
            
            if (posicion~=0 && size(app.data(posicion).signal,2)>1)
                [S,F,T] = spectrogram(app.data(posicion).signal(:,2)',1000,[],[],app.data(posicion).fs);       %Hacemos el espectrograma de un solo canal
                dibPlotEspect2(app,T,F,S,posicion);
                title(app.UIAxes4,'Espectrograma del canal 2 de '+app.data(posicion).nombre);
            else
                errordlg('La señal solo tiene 1 canal');
            end
        end

        % Value changed function: TipoFiltro
        function TipoFiltroValueChanged(app, event)
            value = app.TipoFiltro.Value;
            
            if (strcmp(value,'Paso banda') || strcmp(value,'Elimina banda'))
                app.HzLabel_3.Enable='on';
                app.FrecCorte2EditField.Enable='on';
                app.afrecdecorteLabel.Enable='on';
            else
                app.HzLabel_3.Enable='off';
                app.FrecCorte2EditField.Enable='off';
                app.afrecdecorteLabel.Enable='off';
            end
        end

        % Button pushed function: ModulacionButton
        function ModulacionButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            %obtencion de los parametros de las señales portadora y moduladora
            fc = app.FrecPortField.Value;
            fc=str2double(fc);
            mod = app.TipoDropDown.Value;
            posicion = getSelposicion(app);
            signal = app.data(posicion).signal;
            fs = app.data(posicion).fs;
            aux=app.data(posicion).nombre;
            
            switch(mod)
                case 'AM'
                    y=modulate(signal,fc,fs,'am');
                case 'FM'
                    y=modulate(signal,fc,fs,'fm');
                case 'PM'
                    y=modulate(signal,fc,fs,'pm');
                case 'Elegir..'
                    errordlg('Elija una modulación, por favor');
                    return
            end
            
            aux=aux+", mod "+mod;
            
            includeData(app,y,fs,aux);
            addItem(app,aux);
            
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);            
        end

        % Button pushed function: DemodularButton
        function DemodularButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            %obtencion de los parametros de demoduladora y modulada
            fc = app.FrecPortField.Value;
            fc=str2double(fc);
            mod = app.TipoDropDown.Value;
            posicion = getSelposicion(app);
            signal = app.data(posicion).signal;
            fs = app.data(posicion).fs;
            aux=app.data(posicion).nombre;
            
            switch(mod)
                case 'AM'
                    y=demod(signal,fc,fs,'am');
                case 'FM'
                    y=demod(signal,fc,fs,'fm');
                case 'PM'
                    y=demod(signal,fc,fs,'pm');
                case 'Elegir..'
                    errordlg('Elija una modulación, por favor');
                    return
            end
            
            aux=aux+", demod "+mod;
            
            includeData(app,y,fs,aux);
            addItem(app,aux);
            
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);
        end

        % Button pushed function: InvertirsealButton
        function InvertirsealButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion = getSelposicion(app);
            
            %obtencion de los parametros de la señal
            signal=app.data(posicion).signal;
            y=flip(signal);
            aux=app.data(posicion).nombre;
            
            aux=aux+", invertida";
            
            includeData(app,y,app.data(posicion).fs,aux);
            addItem(app,aux);
            
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);
        end

        % Button pushed function: AplicarCuantButton
        function AplicarCuantButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end    
            
            if app.NmerodebitsSpinner.Value>1
                posicion = getSelposicion(app);
                
                %obtencion de los parametros de la señal a cuantificar
                signal = app.data(posicion).signal;
                aux=app.data(posicion).nombre;
                [~,n]=size(signal);
                
                if n==1 %la señal tiene un canal
                    maximo=max(signal);
                    niveles=2^app.NmerodebitsSpinner.Value;
                    delta=2/niveles;
                    
                    particion= -maximo+delta:delta:maximo-delta;
                    codebook= -maximo+(delta/2):delta:maximo-(delta/2);
                    [~,y]=quantiz(signal,particion,codebook);
                
                elseif n==2 %la señal tiene 2 canales
                    niveles=2^app.NmerodebitsSpinner.Value;
                    delta=2/niveles;
                    
                    maximo1=max(signal(:,1));
                    particion1= -maximo1+delta:delta:maximo1-delta;
                    codebook1= -maximo1+(delta/2):delta:maximo1-(delta/2);
                    
                    maximo2=max(signal(:,2));
                    particion2= -maximo2+delta:delta:maximo2-delta;
                    codebook2= -maximo2+(delta/2):delta:maximo2-(delta/2);
                    
                    [~,y1]=quantiz(signal(:,1),particion1,codebook1);
                    [~,y2]=quantiz(signal(:,2),particion2,codebook2);
                    
                    
                    y=[y1;y2];
                end
                
                aux=aux+", cuant "+int2str(niveles)+" niveles";
                
                includeData(app,y',app.data(posicion).fs,aux);
                addItem(app,aux);
                
                checkInfo(app,app.pointer);
                checkPlotEfecto(app,posicion,app.pointer);
            else
                errordlg('Elija un mayor número de bits por favor');
            end
        
        end

        % Value changed function: VisualizarcolorbarCheckBox
        function VisualizarcolorbarCheckBoxValueChanged(app, event)
            value = app.VisualizarcolorbarCheckBox.Value;
            
            if value==0
                colorbar(app.UIAxes3,'off');
            else
                c=colorbar(app.UIAxes3);
                c.Label.String='Potencia [dBm]';
            end
        end

        % Value changed function: Visualizarcolorbar2CheckBox
        function Visualizarcolorbar2CheckBoxValueChanged(app, event)
            value = app.Visualizarcolorbar2CheckBox.Value;
            if value==0
                colorbar(app.UIAxes4,'off');
            else
                c=colorbar(app.UIAxes4);
                c.Label.String='Potencia [dBm]';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                cla(app.UIAxes,"reset");
                xlabel(app.UIAxes,'Tiempo [s]');
                ylabel(app.UIAxes,'Amplitud [V]');
                title(app.UIAxes,'Gráfica 1');
                
                cla(app.UIAxes2,"reset");
                xlabel(app.UIAxes2,'Tiempo [s]');
                ylabel(app.UIAxes2,'Amplitud [V]');
                title(app.UIAxes2,'Gráfica 2');
                
                cla(app.UIAxes3,"reset");
                xlabel(app.UIAxes3,'Tiempo [s]');
                ylabel(app.UIAxes3,'Frecuencia [Hz]');
                title(app.UIAxes3,'Gráfica 3');
                
                cla(app.UIAxes4,"reset");
                xlabel(app.UIAxes4,'Tiempo [s]');
                ylabel(app.UIAxes4,'Frecuencia [Hz]');
                title(app.UIAxes4,'Gráfica 4');
                
                cla(app.UIAxes5,"reset");
                xlabel(app.UIAxes5,'Frecuencia [Hz]');
                ylabel(app.UIAxes5,'Amplitud [V]');
                title(app.UIAxes5,'Gráfica 5');
                
                cla(app.UIAxes6,"reset");
                xlabel(app.UIAxes6,'Frecuencia [Hz]');
                ylabel(app.UIAxes6,'Amplitud [V]');
                title(app.UIAxes6,'Gráfica 6');
        end

        % Button pushed function: AplicarRuidoButton
        function AplicarRuidoButtonPushed(app, event)
            %obtencion de los parametros del ruido
            tiporuido=app.TipoderuidoDropDown.Value;
            ncanales=str2double(app.CanalesRuido.Value);
            fs=str2double(app.FrecmuestreoRuido.Value);
            nmuestras=app.nmuestrasSpinner.Value;
            
            switch tiporuido
                case 'Blanco'
                    cn=dsp.ColoredNoise('white',nmuestras,ncanales,"BoundedOutput",true);
                    y=cn();
                    
                case 'Rosa'
                    cn=dsp.ColoredNoise('pink',nmuestras,ncanales,"BoundedOutput",true);
                    y=cn();
                    
                case 'Marron'
                    cn=dsp.ColoredNoise('brown',nmuestras,ncanales,"BoundedOutput",true);
                    y=cn();
                    
                case 'Azul'
                    cn=dsp.ColoredNoise('blue',nmuestras,ncanales,"BoundedOutput",true);
                    y=cn();
                    
                case 'Violeta'
                    cn=dsp.ColoredNoise('purple',nmuestras,ncanales,"BoundedOutput",true);
                    y=cn();
                    
                case 'Elegir..'
                    errordlg('Elija un tipo de ruido, por favor');
                    return
            end
            
            aux="Ruido "+tiporuido+" generado, Fs="+app.FrecmuestreoRuido.Value+"Hz "+"nmues="+num2str(nmuestras);
            
            includeData(app,y,fs,aux);
            addItem(app,aux);

            checkInfo(app,app.pointer);
            checkPlotCarga(app,app.pointer);
        end

        % Button pushed function: MostrargrficasButton
        function MostrargrficasButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion=getSelposicion(app);
            
            if (posicion > 0 && strcmp(app.Buffer.Multiselect,'off'))
                draw1(app,posicion);
            end
        end

        % Button pushed function: AplicarAtenuButton
        function AplicarAtenuButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion = getSelposicion(app);
            
            signal = app.data(posicion).signal;
            aux=app.data(posicion).nombre;
            
            if(app.AmplificarSpinner.Value>1)
                y=signal*app.AmplificarSpinner.Value;
                if(app.AtenuarSpinner.Value>1)
                    y=y/app.AtenuarSpinner.Value;
                end  
            
            elseif (app.AtenuarSpinner.Value>1)
                    y=signal/app.AtenuarSpinner.Value;
            end
            
            aux=aux+", amplificada factor "+num2str(app.AmplificarSpinner.Value/app.AtenuarSpinner.Value);
             
            includeData(app,y,app.data(posicion).fs,aux);
            addItem(app,aux);
                
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);
        end

        % Button pushed function: AadirefectosButton
        function AadirefectosButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion=getSelposicion(app);  
            
            %obtencion de parametros de la señal
            y=app.data(posicion).signal;
            fs=app.data(posicion).fs;
            aux=app.data(posicion).nombre;
            ganancia=app.GananciaEcoFlangerEditField.Value;
            delay=app.DelayEcoFlangerEditField.Value/1000;
            mix=app.MixFuzzEditField.Value/100;
            [~,n]=size(y);
            
            if app.EcoCheckBox.Value==true || app.FlangerCheckBox.Value==true || app.FuzzCheckBox.Value==true
                
                if app.EcoCheckBox.Value==true && n==1 %efecto a señal de 1 canal
                    y=generaEco(app,y,delay,ganancia,fs);
                    aux=aux+", con Eco";
                    
                elseif app.EcoCheckBox.Value==true && n==2 %efecto a señal de 2 canales
                    y1=generaEco(app,y(:,1),delay,ganancia,fs);
                    y2=generaEco(app,y(:,2),delay,ganancia,fs);
                    
                    y=[y1,y2];
                    aux=aux+", con Eco";
                end
                
                if app.FlangerCheckBox.Value==true && n==1
                    y=generaFlanger(app,y,delay,ganancia,fs);
                    aux=aux+", con Flanger";
                    
                elseif app.FlangerCheckBox.Value==true && n==2
                    y1=generaEco(app,y(:,1),delay,ganancia,fs);
                    y2=generaEco(app,y(:,2),delay,ganancia,fs);
                    
                    y=[y1,y2];
                    aux=aux+", con Flanger";
                end
                            
                if app.FuzzCheckBox.Value==true && n==1                
                    y=generaFuzz(app,y,ganancia,mix);
                    aux=aux+", con Fuzz";
                    
                elseif app.FuzzCheckBox.Value==true && n==2
                    y1=generaEco(app,y(:,1),delay,ganancia,fs);
                    y2=generaEco(app,y(:,2),delay,ganancia,fs);
                    
                    y=[y1,y2];
                    aux=aux+", con Fuzz";
                end
            
                includeData(app,y,app.data(posicion).fs,aux);
                addItem(app,aux);
                
                checkInfo(app,app.pointer);
                checkPlotEfecto(app,posicion,app.pointer);
            else
                errordlg('Marque al menos una casilla');
            end
            
        end

        % Value changed function: EcoCheckBox
        function EcoCheckBoxValueChanged(app, event)
            value = app.EcoCheckBox.Value;
            
            if value==true
                app.DelayEcoFlangerEditField.Enable='on';
                app.DelayEcoFlangerLabel.Enable='on';
                app.msLabel_2.Enable='on';
                
            elseif value==false && app.FlangerCheckBox.Value==false
                app.DelayEcoFlangerEditField.Enable='off';
                app.DelayEcoFlangerLabel.Enable='off';
                app.msLabel_2.Enable='off';
            end
        end

        % Value changed function: FlangerCheckBox
        function FlangerCheckBoxValueChanged(app, event)
            value = app.FlangerCheckBox.Value;
            
            if value==true
                app.DelayEcoFlangerEditField.Enable='on';
                app.DelayEcoFlangerLabel.Enable='on';
                app.msLabel_2.Enable='on';
                
            elseif value==false && app.EcoCheckBox.Value==false
                app.DelayEcoFlangerEditField.Enable='off';
                app.DelayEcoFlangerLabel.Enable='off';
                app.msLabel_2.Enable='off';
            end
        end

        % Value changed function: FuzzCheckBox
        function FuzzCheckBoxValueChanged(app, event)
            value = app.FuzzCheckBox.Value;
            
            if value==true
                app.MixFuzzEditField.Enable='on';
                app.MixFuzzEditFieldLabel.Enable='on';
                app.Label.Enable='on';
                
            else
                app.MixFuzzEditField.Enable='off';
                app.MixFuzzEditFieldLabel.Enable='off';
                app.Label.Enable='off';
            end
        end

        % Button pushed function: Canal1Tiempo
        function Canal1TiempoButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [t,~]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                dibPlotTiempos1(app,t,app.data(posicion).signal(:,1),app.data(posicion).fs);
                title(app.UIAxes,"Canal 1 de "+app.data(posicion).nombre+" en tiempo");
            end
        end

        % Button pushed function: Canal2Tiempo
        function Canal2TiempoButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if (posicion~=0 && size(app.data(posicion).signal,2)>1)
                [t,~]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                dibPlotTiempos1(app,t,app.data(posicion).signal(:,2),app.data(posicion).fs);
                title(app.UIAxes,"Canal 2 de "+app.data(posicion).nombre+" en tiempo");
            else
                errordlg('La señal solo tiene 1 canal');
            end
        end

        % Button pushed function: Canal1Tiempo_2
        function Canal1Tiempo_2ButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [t,~]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                dibPlotTiempos2(app,t,app.data(posicion).signal(:,1),app.data(posicion).fs);
                title(app.UIAxes2,"Canal 1 de "+app.data(posicion).nombre+" en tiempo");
            end
        end

        % Button pushed function: Canal2Tiempo_2
        function Canal2Tiempo_2ButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if (posicion~=0 && size(app.data(posicion).signal,2)>1)
                [t,~]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                dibPlotTiempos2(app,t,app.data(posicion).signal(:,2),app.data(posicion).fs);
                title(app.UIAxes2,"Canal 2 de "+app.data(posicion).nombre+" en tiempo");
            else
                errordlg('La señal solo tiene 1 canal');
            end
        end

        % Button pushed function: Canal1Frecuencia1
        function Canal1Frecuencia1ButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [~,f]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                TF=(1/length(app.data(posicion).signal(:,1))) * fft(app.data(posicion).signal(:,1));
                TF=TF(1:fix(length(TF)/2)+1);
                
                dibPlotFrecuencias1(app,f,TF);
                title(app.UIAxes5,"Canal 1 de "+app.data(posicion).nombre+" en tiempo");
            end
        end

        % Button pushed function: Canal2Frecuencia2
        function Canal2Frecuencia2ButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [~,f]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                TF=(1/length(app.data(posicion).signal(:,2))) * fft(app.data(posicion).signal(:,2));
                TF=TF(1:fix(length(TF)/2)+1);
                
                dibPlotFrecuencias1(app,f,TF);
                title(app.UIAxes5,"Canal 2 de "+app.data(posicion).nombre+" en tiempo");
            else
                errordlg('La señal solo tiene 1 canal');
            end
        end

        % Button pushed function: Canal1Frecuencia1_2
        function Canal1Frecuencia1_2ButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [~,f]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                TF=(1/length(app.data(posicion).signal(:,1))) * fft(app.data(posicion).signal(:,1));
                TF=TF(1:fix(length(TF)/2)+1);
                
                dibPlotFrecuencias2(app,f,TF);
                title(app.UIAxes6,"Canal 1 de "+app.data(posicion).nombre+" en tiempo");
            end
        end

        % Button pushed function: Canal2Frecuencia2_2
        function Canal2Frecuencia2_2ButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion~=0
                [~,f]=createBase(app,app.data(posicion).fs,length(app.data(posicion).signal));     %Hacemos el espectrograma de un solo canal
                TF=(1/length(app.data(posicion).signal(:,2))) * fft(app.data(posicion).signal(:,2));
                TF=TF(1:fix(length(TF)/2)+1);
                
                dibPlotFrecuencias2(app,f,TF);
                title(app.UIAxes6,"Canal 2 de "+app.data(posicion).nombre+" en tiempo");
            else
                errordlg('La señal solo tiene 1 canal');
            end
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            if(length(app.Buffer.Items)==0)
                errordlg('Seleccione una señal por favor');
                return                
            end
            
            posicion=getSelposicion(app);
            
            %obtencion de parametros de la señal y delay
            delay=app.DelayEditField.Value/1000;
            y=app.data(posicion).signal;
            [~,n]=size(y);
            
            if n==1 %la señal tiene 1 canal
                y=generaDelay(app,y,delay,app.data(posicion).fs);
            else %la señal tiene 2 canales
                y1=generaDelay(app,y(:,1),delay,app.data(posicion).fs);
                y2=generaDelay(app,y(:,2),delay,app.data(posicion).fs);
                y=[y1;y2];
            end
            
            aux=app.data(posicion).nombre+", retardada "+num2str(delay)+" ms";
            
            includeData(app,y',app.data(posicion).fs,aux);
            addItem(app,aux);
                
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if strcmp(selectedTab.Title,'Desfase') && app.desfase==0
                msgbox('Recuerde que si la señal es periódica, puede generar interferencias para luego combinar con la señal original: Destructiva si el desfase es 180. Constructiva si el desfase es 0 o 360');
                app.desfase=1;
            end
        end

        % Button pushed function: AplicarIntDiez
        function AplicarIntDiezButtonPushed(app, event)
            posicion=getSelposicion(app);
            
            if posicion ~= 0
                factInt=app.FactInterpolacionEditField.Value;
                factDiez=app.FactdiezmadoEditField.Value;
                y=resample(app.data(posicion).signal,factInt,factDiez);
            end
            
            aux=app.data(posicion).nombre+", inter/diez x"+num2str(factInt)+"/x"+num2str(factDiez);
            
            includeData(app,y,app.data(posicion).fs,aux);
            addItem(app,aux);
            
            checkInfo(app,app.pointer);
            checkPlotEfecto(app,posicion,app.pointer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Visible = 'off';
            app.Menu.Enable = 'off';
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica 1')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud [V]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gráfica 2')
            xlabel(app.UIAxes2, 'Tiempo [s]')
            ylabel(app.UIAxes2, 'Amplitud [V]')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CompExpTab = uitab(app.TabGroup);
            app.CompExpTab.Title = 'Comp/Exp';
            app.CompresinSpinnerLabel = uilabel(app.CompExpTab);
            app.CompresinSpinnerLabel.Text = 'Compresión';
            app.CompresinSpinner = uispinner(app.CompExpTab);
            app.CompresinSpinner.Limits = [1 Inf];
            app.CompresinSpinner.Value = 1;
            app.ExpansinSpinnerLabel = uilabel(app.CompExpTab);
            app.ExpansinSpinnerLabel.Text = 'Expansión';
            app.ExpansinSpinner = uispinner(app.CompExpTab);
            app.ExpansinSpinner.Limits = [1 Inf];
            app.ExpansinSpinner.Value = 1;
            app.CompExpButton = uibutton(app.CompExpTab, 'push');
            app.CompExpButton.ButtonPushedFcn = createCallbackFcn(app, @CompExpButtonPushed, true);
            app.CompExpButton.Text = 'Aplicar';
            app.CuantificacinTab = uitab(app.TabGroup);
            app.CuantificacinTab.Title = 'Cuantificación';
            app.NmerodebitsSpinnerLabel = uilabel(app.CuantificacinTab);
            app.NmerodebitsSpinnerLabel.Text = 'Número de bits';
            app.NmerodebitsSpinner = uispinner(app.CuantificacinTab);
            app.NmerodebitsSpinner.Limits = [1 Inf];
            app.NmerodebitsSpinner.Value = 1;
            app.AplicarCuantButton = uibutton(app.CuantificacinTab, 'push');
            app.AplicarCuantButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarCuantButtonPushed, true);
            app.AplicarCuantButton.Text = 'Aplicar';
            app.AmpAttTab = uitab(app.TabGroup);
            app.AmpAttTab.Title = 'Amp/Att';
            app.AmplificarSpinnerLabel = uilabel(app.AmpAttTab);
            app.AmplificarSpinnerLabel.Text = 'Amplificar';
            app.AmplificarSpinner = uispinner(app.AmpAttTab);
            app.AmplificarSpinner.Limits = [1 Inf];
            app.AmplificarSpinner.Value = 1;
            app.AtenuarSpinnerLabel = uilabel(app.AmpAttTab);
            app.AtenuarSpinnerLabel.Text = 'Atenuar';
            app.AtenuarSpinner = uispinner(app.AmpAttTab);
            app.AtenuarSpinner.Limits = [1 Inf];
            app.AtenuarSpinner.Value = 1;
            app.AplicarAtenuButton = uibutton(app.AmpAttTab, 'push');
            app.AplicarAtenuButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarAtenuButtonPushed, true);
            app.AplicarAtenuButton.Text = 'Aplicar';
            app.InversinTab = uitab(app.TabGroup);
            app.InversinTab.Title = 'Inversión';
            app.InvertirsealButton = uibutton(app.InversinTab, 'push');
            app.InvertirsealButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirsealButtonPushed, true);
            app.InvertirsealButton.Text = {'Invertir señal '; 'en el tiempo'};
            app.IntpDiezTab = uitab(app.TabGroup);
            app.IntpDiezTab.Title = 'Intp/Diez';
            app.FactInterpolacionEditFieldLabel = uilabel(app.IntpDiezTab);
            app.FactInterpolacionEditFieldLabel.Text = 'Fact. Interpolacion';
            app.FactInterpolacionEditField = uieditfield(app.IntpDiezTab, 'numeric');
            app.FactInterpolacionEditField.Value = 1;
            app.FactdiezmadoEditFieldLabel = uilabel(app.IntpDiezTab);
            app.FactdiezmadoEditFieldLabel.Text = 'Fact. diezmado';
            app.FactdiezmadoEditField = uieditfield(app.IntpDiezTab, 'numeric');
            app.FactdiezmadoEditField.Limits = [1 Inf];
            app.FactdiezmadoEditField.Value = 1;
            app.AplicarIntDiez = uibutton(app.IntpDiezTab, 'push');
            app.AplicarIntDiez.ButtonPushedFcn = createCallbackFcn(app, @AplicarIntDiezButtonPushed, true);
            app.AplicarIntDiez.Text = 'Aplicar';
            app.DesfaseTab = uitab(app.TabGroup);
            app.DesfaseTab.Title = 'Desfase';
            app.DesfasefrecuenciaDelayLabel = uilabel(app.DesfaseTab);
            app.DesfasefrecuenciaDelayLabel.Text = 'Desfase = frecuencia*Delay';
            app.DelayEditFieldLabel = uilabel(app.DesfaseTab);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.DesfaseTab, 'numeric');
            app.DelayEditField.Limits = [0 Inf];
            app.AplicarButton = uibutton(app.DesfaseTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.msLabel = uilabel(app.DesfaseTab);
            app.msLabel.Text = 'ms';
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.AadirefectosButton = uibutton(app.EfectosTab, 'push');
            app.AadirefectosButton.ButtonPushedFcn = createCallbackFcn(app, @AadirefectosButtonPushed, true);
            app.AadirefectosButton.Icon = 'add.png';
            app.AadirefectosButton.Text = 'Añadir efecto/s';
            app.GananciaEcoFlangerEditFieldLabel = uilabel(app.EfectosTab);
            app.GananciaEcoFlangerEditFieldLabel.Text = {'Ganancia'; '(Eco/Flanger)'};
            app.GananciaEcoFlangerEditField = uieditfield(app.EfectosTab, 'numeric');
            app.GananciaEcoFlangerEditField.Limits = [1 Inf];
            app.GananciaEcoFlangerEditField.Value = 1;
            app.DelayEcoFlangerLabel = uilabel(app.EfectosTab);
            app.DelayEcoFlangerLabel.Enable = 'off';
            app.DelayEcoFlangerLabel.Text = {'Delay'; '(Eco/Flanger)'};
            app.DelayEcoFlangerEditField = uieditfield(app.EfectosTab, 'numeric');
            app.DelayEcoFlangerEditField.Limits = [0 Inf];
            app.DelayEcoFlangerEditField.Enable = 'off';
            app.DelayEcoFlangerEditField.Value = 1;
            app.EcoCheckBox = uicheckbox(app.EfectosTab);
            app.EcoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EcoCheckBoxValueChanged, true);
            app.EcoCheckBox.Text = 'Eco';
            app.FlangerCheckBox = uicheckbox(app.EfectosTab);
            app.FlangerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlangerCheckBoxValueChanged, true);
            app.FlangerCheckBox.Text = 'Flanger';
            app.FuzzCheckBox = uicheckbox(app.EfectosTab);
            app.FuzzCheckBox.ValueChangedFcn = createCallbackFcn(app, @FuzzCheckBoxValueChanged, true);
            app.FuzzCheckBox.Text = 'Fuzz';
            app.MixFuzzEditFieldLabel = uilabel(app.EfectosTab);
            app.MixFuzzEditFieldLabel.Enable = 'off';
            app.MixFuzzEditFieldLabel.Text = 'Mix (Fuzz)';
            app.MixFuzzEditField = uieditfield(app.EfectosTab, 'numeric');
            app.MixFuzzEditField.Limits = [0 100];
            app.MixFuzzEditField.Enable = 'off';
            app.MixFuzzEditField.Value = 100;
            app.VLabel_2 = uilabel(app.EfectosTab);
            app.VLabel_2.Text = 'V';
            app.msLabel_2 = uilabel(app.EfectosTab);
            app.msLabel_2.Enable = 'off';
            app.msLabel_2.Text = 'ms';
            app.Label = uilabel(app.EfectosTab);
            app.Label.Enable = 'off';
            app.Label.Text = '%';
            app.GenSenalesPanel = uipanel(app.UIFigure);
            app.GenSenalesPanel.Title = 'Generador de señales simples';
            app.TipodeondaDropDownLabel = uilabel(app.GenSenalesPanel);
            app.TipodeondaDropDownLabel.Text = 'Tipo de onda';
            app.TipoOnda = uidropdown(app.GenSenalesPanel);
            app.TipoOnda.Items = {'Elegir..', 'Seno', 'Coseno', 'Cuadrada', 'Triangular', 'Sierra'};
            app.TipoOnda.Value = 'Elegir..';
            app.AmplitudLabel = uilabel(app.GenSenalesPanel);
            app.AmplitudLabel.Text = 'Amplitud';
            app.AmplitEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.AmplitEditField.Value = '1';
            app.VLabel = uilabel(app.GenSenalesPanel);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.GenSenalesPanel);
            app.HzLabel.Text = 'Hz';
            app.FrecEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecEditField.Value = '10';
            app.FrecuenciaLabel = uilabel(app.GenSenalesPanel);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sLabel = uilabel(app.GenSenalesPanel);
            app.sLabel.Text = 's';
            app.DurationEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.DurationEditField.Value = '2';
            app.DuracinLabel = uilabel(app.GenSenalesPanel);
            app.DuracinLabel.Text = 'Duración';
            app.GenerarButton = uibutton(app.GenSenalesPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.HzLabel_2 = uilabel(app.GenSenalesPanel);
            app.HzLabel_2.Text = 'Hz';
            app.FrecmuestreoEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecmuestreoEditField.Value = '4000';
            app.FrecmuestreoLabel = uilabel(app.GenSenalesPanel);
            app.FrecmuestreoLabel.Text = 'Frec. muestreo';
            app.ControldebuferPanel = uipanel(app.UIFigure);
            app.ControldebuferPanel.Title = 'Control de bufer';
            app.Buffer = uilistbox(app.ControldebuferPanel);
            app.Buffer.Items = {};
            app.Buffer.ValueChangedFcn = createCallbackFcn(app, @BufferValueChanged, true);
            app.Buffer.Value = {};
            app.Seal1Panel = uipanel(app.ControldebuferPanel);
            app.Seal1Panel.Title = 'Señal 1';
            app.MostrargrficasButton = uibutton(app.Seal1Panel, 'push');
            app.MostrargrficasButton.ButtonPushedFcn = createCallbackFcn(app, @MostrargrficasButtonPushed, true);
            app.MostrargrficasButton.Text = 'Mostrar gráficas';
            app.Seal2Panel = uipanel(app.ControldebuferPanel);
            app.Seal2Panel.Title = 'Señal 2';
            app.MostrargrficasButton_2 = uibutton(app.Seal2Panel, 'push');
            app.MostrargrficasButton_2.ButtonPushedFcn = createCallbackFcn(app, @MostrargrficasButton_2Pushed, true);
            app.MostrargrficasButton_2.Text = 'Mostrar gráficas';
            app.CombinarsealesButton = uibutton(app.ControldebuferPanel, 'push');
            app.CombinarsealesButton.ButtonPushedFcn = createCallbackFcn(app, @CombinarsealesButtonPushed, true);
            app.CombinarsealesButton.Icon = 'add.png';
            app.CombinarsealesButton.Text = {'Combinar '; 'señales'};
            app.EliminarsealButton = uibutton(app.ControldebuferPanel, 'push');
            app.EliminarsealButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarsealButtonPushed, true);
            app.EliminarsealButton.Icon = 'trash.png';
            app.EliminarsealButton.Text = {'Eliminar '; 'señal'};
            app.CancelarButton = uibutton(app.ControldebuferPanel, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Icon = 'cancel.png';
            app.CancelarButton.Enable = 'off';
            app.CancelarButton.Visible = 'off';
            app.CancelarButton.Text = 'Cancelar';
            app.CombinarButton = uibutton(app.ControldebuferPanel, 'push');
            app.CombinarButton.ButtonPushedFcn = createCallbackFcn(app, @CombinarButtonPushed, true);
            app.CombinarButton.Icon = 'add.png';
            app.CombinarButton.Enable = 'off';
            app.CombinarButton.Visible = 'off';
            app.CombinarButton.Text = 'Combinar ';
            app.Filtros = uipanel(app.UIFigure);
            app.Filtros.Title = 'Filtrado';
            app.FrecdecorteLabel = uilabel(app.Filtros);
            app.FrecdecorteLabel.Text = 'Frec. de corte';
            app.FrecCorteEditField = uieditfield(app.Filtros, 'text');
            app.FrecCorteEditField.Value = '1';
            app.HzLabel_6 = uilabel(app.Filtros);
            app.HzLabel_6.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.Filtros);
            app.HzLabel_3.Enable = 'off';
            app.HzLabel_3.Text = 'Hz';
            app.FrecCorte2EditField = uieditfield(app.Filtros, 'text');
            app.FrecCorte2EditField.Enable = 'off';
            app.FrecCorte2EditField.Value = '10';
            app.afrecdecorteLabel = uilabel(app.Filtros);
            app.afrecdecorteLabel.Enable = 'off';
            app.afrecdecorteLabel.Text = '2a frec. de corte';
            app.AplicarFiltroButton = uibutton(app.Filtros, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar';
            app.FiltroDropDownLabel = uilabel(app.Filtros);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.TipoFiltro = uidropdown(app.Filtros);
            app.TipoFiltro.Items = {'Elegir..', 'Paso bajo', 'Paso alto', 'Paso banda', 'Elimina banda'};
            app.TipoFiltro.ValueChangedFcn = createCallbackFcn(app, @TipoFiltroValueChanged, true);
            app.TipoFiltro.Value = 'Elegir..';
            app.OrdenLabel = uilabel(app.Filtros);
            app.OrdenLabel.Text = 'Orden';
            app.MitadOrdenBPEditField = uieditfield(app.Filtros, 'text');
            app.MitadOrdenBPEditField.Value = '1';
            app.GananciaEditFieldLabel = uilabel(app.Filtros);
            app.GananciaEditFieldLabel.Text = 'Ganancia';
            app.GananciaEditField = uieditfield(app.Filtros, 'text');
            app.GananciaEditField.Value = '1';
            app.ControldegrficasPanel = uipanel(app.UIFigure);
            app.ControldegrficasPanel.Title = 'Control de gráficas';
            app.Grfica3ButtonGroup = uibuttongroup(app.ControldegrficasPanel);
            app.Grfica3ButtonGroup.Title = 'Gráfica 3';
            app.Canal1Espectrograma = uibutton(app.Grfica3ButtonGroup, 'push');
            app.Canal1Espectrograma.ButtonPushedFcn = createCallbackFcn(app, @Canal1EspectrogramaPushed, true);
            app.Canal1Espectrograma.Text = 'Canal 1';
            app.Canal2Espectrograma = uibutton(app.Grfica3ButtonGroup, 'push');
            app.Canal2Espectrograma.ButtonPushedFcn = createCallbackFcn(app, @Canal2EspectrogramaPushed, true);
            app.Canal2Espectrograma.Text = 'Canal 2';
            app.Grfica4ButtonGroup = uibuttongroup(app.ControldegrficasPanel);
            app.Grfica4ButtonGroup.Title = 'Gráfica 4';
            app.Canal1Espectrograma_2 = uibutton(app.Grfica4ButtonGroup, 'push');
            app.Canal1Espectrograma_2.ButtonPushedFcn = createCallbackFcn(app, @Canal1Espectrograma_2Pushed, true);
            app.Canal1Espectrograma_2.Text = 'Canal 1';
            app.Canal2Espectrograma_2 = uibutton(app.Grfica4ButtonGroup, 'push');
            app.Canal2Espectrograma_2.ButtonPushedFcn = createCallbackFcn(app, @Canal2Espectrograma_2Pushed, true);
            app.Canal2Espectrograma_2.Text = 'Canal 2';
            app.ResetButton = uibutton(app.ControldegrficasPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'reset.png';
            app.ResetButton.Text = 'Reset ';
            app.Grfica1ButtonGroup = uibuttongroup(app.ControldegrficasPanel);
            app.Grfica1ButtonGroup.Title = 'Gráfica 1';
            app.Canal1Tiempo = uibutton(app.Grfica1ButtonGroup, 'push');
            app.Canal1Tiempo.ButtonPushedFcn = createCallbackFcn(app, @Canal1TiempoButtonPushed, true);
            app.Canal1Tiempo.Text = 'Canal 1';
            app.Canal2Tiempo = uibutton(app.Grfica1ButtonGroup, 'push');
            app.Canal2Tiempo.ButtonPushedFcn = createCallbackFcn(app, @Canal2TiempoButtonPushed, true);
            app.Canal2Tiempo.Text = 'Canal 2';
            app.Grfica2ButtonGroup = uibuttongroup(app.ControldegrficasPanel);
            app.Grfica2ButtonGroup.Title = 'Gráfica 2';
            app.Canal1Tiempo_2 = uibutton(app.Grfica2ButtonGroup, 'push');
            app.Canal1Tiempo_2.ButtonPushedFcn = createCallbackFcn(app, @Canal1Tiempo_2ButtonPushed, true);
            app.Canal1Tiempo_2.Text = 'Canal 1';
            app.Canal2Tiempo_2 = uibutton(app.Grfica2ButtonGroup, 'push');
            app.Canal2Tiempo_2.ButtonPushedFcn = createCallbackFcn(app, @Canal2Tiempo_2ButtonPushed, true);
            app.Canal2Tiempo_2.Text = 'Canal 2';
            app.Grfica5ButtonGroup = uibuttongroup(app.ControldegrficasPanel);
            app.Grfica5ButtonGroup.Title = 'Gráfica 5';
            app.Canal1Frecuencia1 = uibutton(app.Grfica5ButtonGroup, 'push');
            app.Canal1Frecuencia1.ButtonPushedFcn = createCallbackFcn(app, @Canal1Frecuencia1ButtonPushed, true);
            app.Canal1Frecuencia1.Text = 'Canal 1';
            app.Canal2Frecuencia2 = uibutton(app.Grfica5ButtonGroup, 'push');
            app.Canal2Frecuencia2.ButtonPushedFcn = createCallbackFcn(app, @Canal2Frecuencia2ButtonPushed, true);
            app.Canal2Frecuencia2.Text = 'Canal 2';
            app.Grfica6ButtonGroup = uibuttongroup(app.ControldegrficasPanel);
            app.Grfica6ButtonGroup.Title = 'Gráfica 6';
            app.Canal1Frecuencia1_2 = uibutton(app.Grfica6ButtonGroup, 'push');
            app.Canal1Frecuencia1_2.ButtonPushedFcn = createCallbackFcn(app, @Canal1Frecuencia1_2ButtonPushed, true);
            app.Canal1Frecuencia1_2.Text = 'Canal 1';
            app.Canal2Frecuencia2_2 = uibutton(app.Grfica6ButtonGroup, 'push');
            app.Canal2Frecuencia2_2.ButtonPushedFcn = createCallbackFcn(app, @Canal2Frecuencia2_2ButtonPushed, true);
            app.Canal2Frecuencia2_2.Text = 'Canal 2';
            app.Grficastiempo1y2Label = uilabel(app.ControldegrficasPanel);
            app.Grficastiempo1y2Label.Text = 'Gráficas tiempo 1 y 2';
            app.Grficasespectrograma3y4Label = uilabel(app.ControldegrficasPanel);
            app.Grficasespectrograma3y4Label.Text = 'Gráficas espectrograma 3 y 4';
            app.Grficasfrecuencia5y6Label = uilabel(app.ControldegrficasPanel);
            app.Grficasfrecuencia5y6Label.Text = 'Gráficas frecuencia 5 y 6';
            app.InformacindelasealPanel = uipanel(app.UIFigure);
            app.InformacindelasealPanel.Title = 'Información de la señal';
            app.TextArea = uitextarea(app.InformacindelasealPanel);
            app.ModulacinPanel = uipanel(app.UIFigure);
            app.ModulacinPanel.Title = 'Modulación';
            app.TipoDropDownLabel = uilabel(app.ModulacinPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.ModulacinPanel);
            app.TipoDropDown.Items = {'Elegir..', 'AM', 'FM', 'PM'};
            app.TipoDropDown.Value = 'Elegir..';
            app.HzLabel_7 = uilabel(app.ModulacinPanel);
            app.HzLabel_7.Text = 'Hz';
            app.FrecPortField = uieditfield(app.ModulacinPanel, 'text');
            app.FrecPortField.Value = '1000';
            app.FrecportadoraLabel = uilabel(app.ModulacinPanel);
            app.FrecportadoraLabel.Text = 'Frec. portadora';
            app.ModulacionButton = uibutton(app.ModulacinPanel, 'push');
            app.ModulacionButton.ButtonPushedFcn = createCallbackFcn(app, @ModulacionButtonPushed, true);
            app.ModulacionButton.Text = 'Modular';
            app.DemodularButton = uibutton(app.ModulacinPanel, 'push');
            app.DemodularButton.ButtonPushedFcn = createCallbackFcn(app, @DemodularButtonPushed, true);
            app.DemodularButton.Text = 'Demodular';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.EspectogramaTab = uitab(app.TabGroup2);
            app.EspectogramaTab.Title = 'Espectograma';
            app.UIAxes3 = uiaxes(app.EspectogramaTab);
            title(app.UIAxes3, 'Gráfica 3')
            xlabel(app.UIAxes3, 'Tiempo [s]')
            ylabel(app.UIAxes3, 'Frecuencia [Hz]')
            app.VisualizarcolorbarCheckBox = uicheckbox(app.EspectogramaTab);
            app.VisualizarcolorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizarcolorbarCheckBoxValueChanged, true);
            app.VisualizarcolorbarCheckBox.Text = 'Visualizar colorbar';
            app.VisualizarcolorbarCheckBox.Value = true;
            app.SealenfrecuenciaTab_2 = uitab(app.TabGroup2);
            app.SealenfrecuenciaTab_2.Title = 'Señal en frecuencia';
            app.UIAxes5 = uiaxes(app.SealenfrecuenciaTab_2);
            title(app.UIAxes5, 'Gráfica 5')
            xlabel(app.UIAxes5, 'Frecuencia [Hz]')
            ylabel(app.UIAxes5, 'Amplitud [V]')
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.EspectrogramaTab = uitab(app.TabGroup3);
            app.EspectrogramaTab.Title = 'Espectrograma';
            app.UIAxes4 = uiaxes(app.EspectrogramaTab);
            title(app.UIAxes4, 'Gráfica 4')
            xlabel(app.UIAxes4, {'Tiempo [s]'; ''})
            ylabel(app.UIAxes4, 'Frecuencia [Hz]')
            app.Visualizarcolorbar2CheckBox = uicheckbox(app.EspectrogramaTab);
            app.Visualizarcolorbar2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Visualizarcolorbar2CheckBoxValueChanged, true);
            app.Visualizarcolorbar2CheckBox.Text = 'Visualizar colorbar';
            app.Visualizarcolorbar2CheckBox.Value = true;
            app.SealenfrecuenciaTab = uitab(app.TabGroup3);
            app.SealenfrecuenciaTab.Title = 'Señal en frecuencia';
            app.UIAxes6 = uiaxes(app.SealenfrecuenciaTab);
            title(app.UIAxes6, 'Gráfica 6')
            xlabel(app.UIAxes6, 'Frecuencia [Hz]')
            ylabel(app.UIAxes6, 'Amplitud [V]')
            app.GeneradorderuidoPanel = uipanel(app.UIFigure);
            app.GeneradorderuidoPanel.Title = 'Generador de ruido';
            app.TipoderuidoDropDownLabel = uilabel(app.GeneradorderuidoPanel);
            app.TipoderuidoDropDownLabel.Text = 'Tipo de ruido';
            app.TipoderuidoDropDown = uidropdown(app.GeneradorderuidoPanel);
            app.TipoderuidoDropDown.Items = {'Elegir..', 'Blanco', 'Rosa', 'Marron', 'Azul', 'Violeta'};
            app.TipoderuidoDropDown.Value = 'Elegir..';
            app.CanalesDropDown_2Label = uilabel(app.GeneradorderuidoPanel);
            app.CanalesDropDown_2Label.Text = 'Canales';
            app.CanalesRuido = uidropdown(app.GeneradorderuidoPanel);
            app.CanalesRuido.Items = {'1', '2', ''};
            app.CanalesRuido.Value = '1';
            app.HzLabel_9 = uilabel(app.GeneradorderuidoPanel);
            app.HzLabel_9.Text = 'Hz';
            app.FrecmuestreoRuido = uieditfield(app.GeneradorderuidoPanel, 'text');
            app.FrecmuestreoRuido.Value = '4000';
            app.FsLabel = uilabel(app.GeneradorderuidoPanel);
            app.FsLabel.Text = 'Fs';
            app.AplicarRuidoButton = uibutton(app.GeneradorderuidoPanel, 'push');
            app.AplicarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRuidoButtonPushed, true);
            app.AplicarRuidoButton.Text = 'Generar';
            app.nmuestrasSpinnerLabel = uilabel(app.GeneradorderuidoPanel);
            app.nmuestrasSpinnerLabel.Text = 'nº muestras';
            app.nmuestrasSpinner = uispinner(app.GeneradorderuidoPanel);
            app.nmuestrasSpinner.Limits = [0 Inf];
            app.nmuestrasSpinner.Value = 1000;
            app.Panel = uipanel(app.UIFigure);
            app.CargaButton = uibutton(app.Panel, 'push');
            app.CargaButton.ButtonPushedFcn = createCallbackFcn(app, @CargaButtonPushed, true);
            app.CargaButton.Icon = 'buscar.png';
            app.CargaButton.Text = 'Carga';
            app.GuardarButton = uibutton(app.Panel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Icon = 'guardar.png';
            app.GuardarButton.Text = 'Guardar';
            app.HzLabel_8 = uilabel(app.Panel);
            app.HzLabel_8.Text = 'Hz';
            app.FsGrabacionField = uieditfield(app.Panel, 'text');
            app.FsGrabacionField.Value = '16000';
            app.FsrecLabel = uilabel(app.Panel);
            app.FsrecLabel.Text = 'Fs rec';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'play.png';
            app.PlayButton.Text = '';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause.png';
            app.PauseButton.Text = '';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop.png';
            app.StopButton.Text = '';
            app.RecordButton = uibutton(app.Panel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Icon = 'rec.png';
            app.RecordButton.Text = {''; ''};
            app.nbitsDropDownLabel = uilabel(app.Panel);
            app.nbitsDropDownLabel.Text = 'nº bits';
            app.nbitsDropDown = uidropdown(app.Panel);
            app.nbitsDropDown.Items = {'8', '16', '24'};
            app.nbitsDropDown.Value = '16';
            app.CanalesDropDownLabel = uilabel(app.Panel);
            app.CanalesDropDownLabel.Text = 'Canales';
            app.CanalesDropDown = uidropdown(app.Panel);
            app.CanalesDropDown.Items = {'1', '2', ''};
            app.CanalesDropDown.Value = '1';
            app.Panel_2 = uipanel(app.UIFigure);
            app.DibujarCargaCheckBox = uicheckbox(app.Panel_2);
            app.DibujarCargaCheckBox.Text = 'Dibujar al cargar/grabar';
            app.DibujarCargaCheckBox.Value = true;
            app.DibujarEfectoCheckBox = uicheckbox(app.Panel_2);
            app.DibujarEfectoCheckBox.Text = 'Dibujar al aplicar un efecto';
            app.DibujarEfectoCheckBox.Value = true;
            app.InfoCheckBox = uicheckbox(app.Panel_2);
            app.InfoCheckBox.Text = {'Mostrar información de la señal '; 'al cargar, grabar, o aplicar un efecto'};
            app.InfoCheckBox.Value = true;
            app.VisualizarReproduccion = uicheckbox(app.Panel_2);
            app.VisualizarReproduccion.Text = 'Visualizar reproducción de audio en gráficas 1 y 3';
            app.VisualizarReproduccion.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end